classdef uhfsae_data_acquisition_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ArduinoConnectionPanel        matlab.ui.container.Panel
        ConnectButton                 matlab.ui.control.Button
        ArduinoDropDownLabel          matlab.ui.control.Label
        ArduinoDropDown               matlab.ui.control.DropDown
        SerialPortEditFieldLabel      matlab.ui.control.Label
        SerialPortEditField           matlab.ui.control.EditField
        ConnectionLamp                matlab.ui.control.Lamp
        TextArea                      matlab.ui.control.TextArea
        LoadCellGroup                 matlab.ui.container.TabGroup
        FrontLeftTab                  matlab.ui.container.Tab
        DataOutPinEditFieldLabel      matlab.ui.control.Label
        FLDataOutPinEditField         matlab.ui.control.EditField
        ClockSCKPinEditFieldLabel     matlab.ui.control.Label
        FLClockSCKPinEditField        matlab.ui.control.EditField
        TareValueEditFieldLabel       matlab.ui.control.Label
        FLTareValueEditField          matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel     matlab.ui.control.Label
        FLScaleFactorEditField        matlab.ui.control.NumericEditField
        MaxLoadgEditFieldLabel        matlab.ui.control.Label
        FLMaxLoadgEditField           matlab.ui.control.NumericEditField
        FLTareButton                  matlab.ui.control.Button
        FLCalibrateButton             matlab.ui.control.Button
        KnownWeightgLabel             matlab.ui.control.Label
        FLKnownWeightgEditField       matlab.ui.control.NumericEditField
        FrontRightTab                 matlab.ui.container.Tab
        DataOutPinEditFieldLabel_2    matlab.ui.control.Label
        FRDataOutPinEditField         matlab.ui.control.EditField
        ClockSCKPinEditFieldLabel_2   matlab.ui.control.Label
        FRClockSCKPinEditField        matlab.ui.control.EditField
        TareValueEditFieldLabel_2     matlab.ui.control.Label
        FRTareValueEditField          matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel_2   matlab.ui.control.Label
        FRScaleFactorEditField        matlab.ui.control.NumericEditField
        MaxLoadgEditFieldLabel_2      matlab.ui.control.Label
        FRMaxLoadgEditField           matlab.ui.control.NumericEditField
        FRTareButton                  matlab.ui.control.Button
        FRCalibrateButton             matlab.ui.control.Button
        KnownWeightgLabel_2           matlab.ui.control.Label
        FRKnownWeightgEditField       matlab.ui.control.NumericEditField
        RearLeftTab                   matlab.ui.container.Tab
        DataOutPinEditFieldLabel_3    matlab.ui.control.Label
        RLDataOutPinEditField         matlab.ui.control.EditField
        ClockSCKPinEditFieldLabel_3   matlab.ui.control.Label
        RLClockSCKPinEditField        matlab.ui.control.EditField
        TareValueEditFieldLabel_3     matlab.ui.control.Label
        RLTareValueEditField          matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel_3   matlab.ui.control.Label
        RLScaleFactorEditField        matlab.ui.control.NumericEditField
        MaxLoadgEditFieldLabel_3      matlab.ui.control.Label
        RLMaxLoadgEditField           matlab.ui.control.NumericEditField
        RLTareButton                  matlab.ui.control.Button
        RLCalibrateButton             matlab.ui.control.Button
        KnownWeightgLabel_3           matlab.ui.control.Label
        RLKnownWeightgEditField       matlab.ui.control.NumericEditField
        RearRightTab                  matlab.ui.container.Tab
        DataOutPinEditFieldLabel_4    matlab.ui.control.Label
        RRDataOutPinEditField         matlab.ui.control.EditField
        ClockSCKPinEditFieldLabel_4   matlab.ui.control.Label
        RRClockSCKPinEditField        matlab.ui.control.EditField
        TareValueEditFieldLabel_4     matlab.ui.control.Label
        RRTareValueEditField          matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel_4   matlab.ui.control.Label
        RRScaleFactorEditField        matlab.ui.control.NumericEditField
        MaxLoadgEditFieldLabel_4      matlab.ui.control.Label
        RRMaxLoadgEditField           matlab.ui.control.NumericEditField
        RRTareButton                  matlab.ui.control.Button
        RRCalibrateButton             matlab.ui.control.Button
        KnownWeightgLabel_4           matlab.ui.control.Label
        RRKnownWeightgEditField       matlab.ui.control.NumericEditField
        Panel                         matlab.ui.container.Panel
        FLReceiveDataButton           matlab.ui.control.Button
        FLDataLamp                    matlab.ui.control.Lamp
        FLStopDataButton              matlab.ui.control.Button
        PauseReadingsButton           matlab.ui.control.Button
        ResumeReadingsButton          matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        FLLoadCell                    matlab.ui.control.NumericEditField
        RetrieveRawValueButton        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        FLLabel                       matlab.ui.control.Label
        LoadCellLabel                 matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        FRLoadCell                    matlab.ui.control.NumericEditField
        FRLabel                       matlab.ui.control.Label
        RLLoadCell                    matlab.ui.control.NumericEditField
        RLLabel                       matlab.ui.control.Label
        RRLoadCell                    matlab.ui.control.NumericEditField
        RRLabel                       matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        TorsionalRigidityCalculationPanel  matlab.ui.container.Panel
        GetTorsionalRigidityButton    matlab.ui.control.Button
        TorsionalRigidityOutput       matlab.ui.control.NumericEditField
        DeformationAngleLabel         matlab.ui.control.Label
        DeformationAngle1EditField    matlab.ui.control.NumericEditField
        Button                        matlab.ui.control.StateButton
        Button_2                      matlab.ui.control.StateButton
        Button_3                      matlab.ui.control.StateButton
        DialIndicatorsLabel           matlab.ui.control.Label
        DeformationAngle1EditField_2  matlab.ui.control.NumericEditField
        FLabel                        matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        SaveLamp                      matlab.ui.control.Lamp
        SaveAllValuestoExcelSheetsLabel  matlab.ui.control.Label
        SaveTorsionalRigidity         matlab.ui.control.Switch
        LoadCellValuesandTorsionalRigidityValueswithTimeLabel  matlab.ui.control.Label
        DialIndicatorSettingsPanel    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        DialIndicatorDisplacementsInLabel  matlab.ui.control.Label
        FrontLeftLabel                matlab.ui.control.Label
        FLDialIndicator               matlab.ui.control.NumericEditField
        FrontRightLabel               matlab.ui.control.Label
        FRDialIndicator               matlab.ui.control.NumericEditField
        RearLeftLabel                 matlab.ui.control.Label
        RLDialIndicator               matlab.ui.control.NumericEditField
        RearRightLabel                matlab.ui.control.Label
        RRDialIndicator               matlab.ui.control.NumericEditField
        ResetDisplacedValuesButton    matlab.ui.control.Button
        DistanceBetweenDialIndicatorsinEditFieldLabel  matlab.ui.control.Label
        FrontDialIndicatorDistance    matlab.ui.control.NumericEditField
        FrontLabel_2                  matlab.ui.control.Label
        RearLabel                     matlab.ui.control.Label
        RearDialIndicatorDistance     matlab.ui.control.NumericEditField
        DistanceBetweenHubsinLabel    matlab.ui.control.Label
        FrontHubDistance              matlab.ui.control.NumericEditField
        FrontLabel_3                  matlab.ui.control.Label
        RearLabel_2                   matlab.ui.control.Label
        RearHubDistance               matlab.ui.control.NumericEditField
        Tab_2                         matlab.ui.container.Tab
        Tab_3                         matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        ard % The arduino
        type % The arduino type
        serial % The port that the arduino is using, ex. 'COM3'
        connected % Connection status
        loadCell1 % Load cells 1-4
        loadCell2
        loadCell3
        loadCell4
        tareReady = 0 % Counter for starting tare function
        tareN = 10 % number of readings for tare Value
        calibrateReady = 0 % Counter for starting calibration function
        calN = 10 % number of readings for calibration Value
        FLscalar = 0 % Value to scale readings to max weight of load cell
        FRscalar = 0
        RLscalar = 0
        RRscalar = 0
        knownWeight % a weight in grams used to calibrate the load cell
        FLdataReady = 0 % a number that allows the data reading of the FL load cell to be toggled
        FRdataReady = 0 % "" for FR load cell
        RLdataReady = 0
        RRdataReady = 0
        activeLoadCells % count of load cells displaying data
        pauseValue = 0 % a way to pause and unpause data collection/plotting
        torsionalRigidity = 0 %the torsional rigidity, equation is below
        % (Ct=(Load Cell Force (FL Hub) * (½ * distance between "Jack Screws"))/
        % (arctan[(displacement dial indicator (FL Hub) + displacement dial indicator (FR Hub))/Distance between dial indicators])
        hubDistanceFront % distance between jack screws in the front
        hubDistanceRear % same for rear
        dialIndDistance % distance between dial indicators
        deformationAngleFront % angle of deformation between dial indicators
        deformationAngleRear % "" rear
        torque % torque
        torsionalRigidityTable %table of Values in the displayed table
        xVals % time Values from graph for export
        yVals1 % load cell 1 (FL) Values from graph for export
        yVals2 % load cell 2 (FR) Values from graph for export
        yVals3 % "" RL
        yVals4 % "" RR
    end
    
    properties (Access = public)
        FLtareValue = 0;
        FRtareValue = 0;
        RLtareValue = 0;
        RRtareValue = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'UH FSAE Torsional Rigidity Calculator';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.TextArea.Value = 'Please Wait for Connection Confirmation.';
            app.serial = app.SerialPortEditField.Value;
            app.type=app.ArduinoDropDown.Value;
            app.ard = arduino(app.serial,app.type,'libraries',{'basicHX711/basic_HX711'});
            % Pins [D4 and D5],[D2 and D3],[D7 and D6],[D8 and D9] are data (Serial Data Output[DOUT]) then clock (Serial Clock Input[SCK])
            app.loadCell1 = addon(app.ard,'basicHX711/basic_HX711',{app.FLDataOutPinEditField.Value,app.FLClockSCKPinEditField.Value});
            app.TextArea.Value = '1st Load Cell connected.';
            app.loadCell2 = addon(app.ard,'basicHX711/basic_HX711',{app.FRDataOutPinEditField.Value,app.FRClockSCKPinEditField.Value}); % load cell 2
            app.TextArea.Value = '2nd Load cell connected.';
            app.loadCell3 = addon(app.ard,'basicHX711/basic_HX711',{app.RLDataOutPinEditField.Value,app.RLClockSCKPinEditField.Value});
            app.TextArea.Value = '3rd Load Cell connected.';
            app.loadCell4 = addon(app.ard,'basicHX711/basic_HX711',{app.RRDataOutPinEditField.Value,app.RRClockSCKPinEditField.Value});
            app.TextArea.Value = '4th Load Cell connected.';
            app.ConnectionLamp.Color = [0 1 0 ];
            app.TextArea.Value = 'Connected.';
            app.activeLoadCells = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 229};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: FLTareButton
        function FLTareButtonPushed(app, event)
            if app.tareReady < 1
                app.TextArea.Value = 'Please confirm weight is off loadcell for taring, then click tare again.';
                app.tareReady = app.tareReady+1;
            else
                app.TextArea.Value = 'Calculating tare Value. Please wait.';
                x=zeros(app.tareN,1);
                for i = 1:app.tareN
                    x(i) = abs(read_HX711(app.loadCell1));
                    pause(1/100);
                end
                app.FLtareValue = sum(x)/app.tareN;
                app.FLTareValueEditField.Value = app.FLtareValue;
                app.TextArea.Value = 'Tare Value is calculated and displayed in the tare field.';
            end
        end

        % Button pushed function: FRTareButton
        function FRTareButtonPushed(app, event)
            if app.tareReady < 1
                app.TextArea.Value = 'Please confirm weight is off loadcell for taring, then click tare again.';
                app.tareReady = app.tareReady+1;
            else
                app.TextArea.Value = 'Calculating tare Value. Please wait.';
                x=zeros(app.tareN,1);
                for i = 1:app.tareN
                    x(i) = abs(read_HX711(app.loadCell2));
                    pause(1/100);
                end
                app.FRtareValue = sum(x)/app.tareN;
                app.FRTareValueEditField.Value = app.FRtareValue;
                app.TextArea.Value = 'Tare Value is calculated and displayed in the tare field.';
            end
        end

        % Button pushed function: RLTareButton
        function RLTareButtonPushed(app, event)
            if app.tareReady < 1
                app.TextArea.Value = 'Please confirm weight is off loadcell for taring, then click tare again.';
                app.tareReady = app.tareReady+1;
            else
                app.TextArea.Value = 'Calculating tare Value. Please wait.';
                x=zeros(app.tareN,1);
                for i = 1:app.tareN
                    x(i) = abs(read_HX711(app.loadCell3));
                    pause(1/100);
                end
                app.RLtareValue = sum(x)/app.tareN;
                app.RLTareValueEditField.Value = app.RLtareValue;
                app.TextArea.Value = 'Tare Value is calculated and displayed in the tare field.';
            end
        end

        % Button pushed function: RRTareButton
        function RRTareButtonPushed(app, event)
            if app.tareReady < 1
                app.TextArea.Value = 'Please confirm weight is off loadcell for taring, then click tare again.';
                app.tareReady = app.tareReady+1;
            else
                app.TextArea.Value = 'Calculating tare Value. Please wait.';
                x=zeros(app.tareN,1);
                for i = 1:app.tareN
                    x(i) = abs(read_HX711(app.loadCell4));
                    pause(1/100);
                end
                app.RRtareValue = sum(x)/app.tareN;
                app.RRTareValueEditField.Value = app.RRtareValue;
                app.TextArea.Value = 'Tare Value is calculated and displayed in the tare field.';
            end
        end

        % Button pushed function: FLCalibrateButton
        function FLCalibrateButtonPushed(app, event)
            if app.calibrateReady < 1
                app.TextArea.Value = 'Please place a known weight on the load cell and enter its weight in grams.';
                app.calibrateReady = app.calibrateReady+1;
            else
                app.TextArea.Value = 'Beginning calibration.';
                y=zeros(app.calN,1);
                for j = 1:app.calN
                   y(j) = abs(read_HX711(app.loadCell1));
                   pause(1/100);
                end
                if app.FLKnownWeightgEditField.Value > 0
                    app.FLscalar = abs(((sum(y)/app.calN)-app.FLtareValue))/app.FLKnownWeightgEditField.Value;
                    app.FLScaleFactorEditField.Value = app.FLscalar;
                    app.TextArea.Value = 'The load cell is calibrated.';
                else
                    app.TextArea.Value = 'You need to input a known weight for the load cell.';
                end
            end
        end

        % Button pushed function: FRCalibrateButton
        function FRCalibrateButtonPushed(app, event)
            if app.calibrateReady < 1
                app.TextArea.Value = 'Please place a known weight on the load cell and enter its weight in grams. Press calibrate when ready.';
                app.calibrateReady = app.calibrateReady+1;
            else
                app.TextArea.Value = 'Beginning calibration.';
                y=zeros(app.calN,1);
                for j = 1:app.calN
                   y(j) = abs(read_HX711(app.loadCell2));
                   pause(1/100);
                end
                if app.FRKnownWeightgEditField.Value > 0
                    app.FRscalar = abs(((sum(y)/app.calN)-app.FRtareValue))/app.FRKnownWeightgEditField.Value;
                    app.FRScaleFactorEditField.Value = app.FRscalar;
                    app.TextArea.Value = 'The load cell is calibrated.';
                else
                    app.TextArea.Value = 'You need to input a known weight for the load cell.';
                end
            end
        end

        % Button pushed function: RLCalibrateButton
        function RLCalibrateButtonPushed(app, event)
            if app.calibrateReady < 1
                app.TextArea.Value = 'Please place a known weight on the load cell and enter its weight in grams. Press calibrate when ready.';
                app.calibrateReady = app.calibrateReady+1;
            else
                app.TextArea.Value = 'Beginning calibration.';
                y=zeros(app.calN,1);
                for j = 1:app.calN
                   y(j) = abs(read_HX711(app.loadCell3));
                   pause(1/100);
                end
                if app.RLKnownWeightgEditField.Value > 0
                    app.RLscalar = abs(((sum(y)/app.calN)-app.RLtareValue))/app.RLKnownWeightgEditField.Value;
                    app.RLScaleFactorEditField.Value = app.RLscalar;
                    app.TextArea.Value = 'The load cell is calibrated.';
                else
                    app.TextArea.Value = 'You need to input a known weight for the load cell.';
                end
            end
        end

        % Button pushed function: RRCalibrateButton
        function RRCalibrateButtonPushed(app, event)
            if app.calibrateReady < 1
                app.TextArea.Value = 'Please place a known weight on the load cell and enter its weight in grams. Press calibrate when ready.';
                app.calibrateReady = app.calibrateReady+1;
            else
                app.TextArea.Value = 'Beginning calibration.';
                y=zeros(app.calN,1);
                for j = 1:app.calN
                   y(j) = abs(read_HX711(app.loadCell4));
                   pause(1/100);
                end
                if app.RRKnownWeightgEditField.Value > 0
                    app.RRscalar = abs(((sum(y)/app.calN)-app.RRtareValue))/app.RRKnownWeightgEditField.Value;
                    app.RRScaleFactorEditField.Value = app.RRscalar;
                    app.TextArea.Value = 'The load cell is calibrated.';
                else
                    app.TextArea.Value = 'You need to input a known weight for the load cell.';
                end
            end
        end

        % Button pushed function: FLReceiveDataButton
        function FLReceiveDataButtonPushed(app, event)
            app.TextArea.Value = 'Attempting to read data.';
            app.FLdataReady=1;
            app.pauseValue=1;
            if app.FLtareValue == 0
                app.FLtareValue = app.FLTareValueEditField.Value;
            end
            if app.FLscalar == 0
                app.FLscalar = app.FLScaleFactorEditField.Value;
            end
            if app.FRtareValue == 0
                app.FRtareValue = app.FRTareValueEditField.Value;
            end
            if app.FRscalar == 0
                app.FRscalar = app.FRScaleFactorEditField.Value;
            end
            if app.RLtareValue == 0
                app.RLtareValue = app.RLTareValueEditField.Value;
            end
            if app.RLscalar == 0
                app.RLscalar = app.RLScaleFactorEditField.Value;
            end
            if app.RRtareValue == 0
                app.RRtareValue = app.RRTareValueEditField.Value;
            end
            if app.RRscalar == 0
                app.RRscalar = app.RRScaleFactorEditField.Value;
            end
            y=[];
            y2=[];
            y3=[];
            y4=[];
            x=[];
            tic();
            hold(app.UIAxes,'on')
            while mod(app.FLdataReady,2) 
                if mod(app.pauseValue,2)
                    app.TextArea.Value = 'Reading data.';
                    app.activeLoadCells = app.activeLoadCells + 1;
                    app.FLDataLamp.Color = [0 1 0];
                    
                    %plotting FL load cell
                    y=[y,(abs(read_HX711(app.loadCell1))-app.FLtareValue)/app.FLscalar]; %#ok<AGROW> 
                    x=[x,toc()]; %#ok<AGROW> 
                    app.FLLoadCell.Value=(abs(read_HX711(app.loadCell1))-app.FLtareValue)/app.FLscalar;
                    plot(app.UIAxes,x,y,'g');
                    
                    %plotting FR load cell
                    y2=[y2,abs((read_HX711(app.loadCell2))-app.FRtareValue)/app.FRscalar]; %#ok<AGROW> 
                    app.FRLoadCell.Value=(abs(read_HX711(app.loadCell2))-app.FRtareValue)/app.FRscalar;
                    plot(app.UIAxes,x,y2,'b');
                    
                    %plotting RL load cell
                    y3=[y3,abs((read_HX711(app.loadCell3))-app.RLtareValue)/app.RLscalar]; %#ok<AGROW> 
                    app.RLLoadCell.Value=(abs(read_HX711(app.loadCell3))-app.RLtareValue)/app.RLscalar;
                    plot(app.UIAxes,x,y3,'r');
                    
                    %plotting RR load cell
                    y4=[y4,abs((read_HX711(app.loadCell4))-app.RRtareValue)/app.RRscalar]; %#ok<AGROW> 
                    app.RRLoadCell.Value=(abs(read_HX711(app.loadCell4))-app.RRtareValue)/app.RRscalar;
                    plot(app.UIAxes,x,y4,'c');
                    
                    %calculation of deformation angle
                    %
                    %commented out old method of calculation
                    %
                     %app.deformationAngle = abs(rad2deg(atan((abs(app.RLDialIndicator.Value-app.RRDialIndicator.Value)...
                     % -abs(app.FLDialIndicator.Value-app.FRDialIndicatorEditField.Value))...
                     % /app.FrontDialIndicatorDistance.Value)));
                     % app.DeformationAngle1EditField.Value = app.deformationAngle;
                    app.deformationAngleFront = rad2deg(atan(abs(app.FLDialIndicator.Value-app.FRDialIndicator.Value)/app.FrontDialIndicatorDistance.Value));
                    app.DeformationAngle1EditField.Value = app.deformationAngleFront;
                    app.deformationAngleRear = rad2deg(atan(abs(app.RLDialIndicator.Value-app.RRDialIndicator.Value)/app.RearDialIndicatorDistance.Value));
                    app.DeformationAngle1EditField_2.Value = app.deformationAngleRear;
                    
                    %Values to be saved in excel
                    app.xVals=x;
                    app.yVals1=y;
                    app.yVals2=y;
                    app.yVals3=y;
                    app.yVals4=y;
                else
                    app.TextArea.Value = 'Data reading paused.';
                    pause(1/100);
                end
            end
        end

        % Button pushed function: FLStopDataButton
        function FLStopDataButtonPushed(app, event)
            app.FLdataReady=app.FLdataReady + 1;
            app.activeLoadCells = app.activeLoadCells - 1;
            app.FLDataLamp.Color = [0 0 0];
            app.TextArea.Value = 'Data reading is stopped.';
        end

        % Button pushed function: RetrieveRawValueButton
        function RetrieveRawValueButtonPushed(app, event)
            if app.FLtareValue == 0
                app.FLtareValue = app.FLTareValueEditField.Value;
            end
            if app.FRtareValue == 0
                app.FRtareValue = app.FRTareValueEditField.Value;
            end
            if app.FLscalar == 0
                app.FLscalar = app.FLScaleFactorEditField.Value;
            end
            if app.FRscalar == 0
                app.FRscalar = app.FRScaleFactorEditField.Value;
            end
            app.FLLoadCell.Value=(abs(read_HX711(app.loadCell1))-app.FLtareValue)/app.FLscalar;
            app.FRLoadCell.Value=(abs(read_HX711(app.loadCell2))-app.FRtareValue)/app.FRscalar;
            app.RLLoadCell.Value=(abs(read_HX711(app.loadCell3))-app.FLtareValue)/app.FLscalar;
            app.RRLoadCell.Value=(abs(read_HX711(app.loadCell4))-app.FRtareValue)/app.FRscalar;            
        end

        % Button pushed function: PauseReadingsButton
        function PauseReadingsButtonPushed(app, event)
            app.TextArea.Value = 'Pausing data...';
            app.pauseValue = app.pauseValue + 1;
            app.FLDataLamp.Color = [0 0 1];
        end

        % Button pushed function: ResumeReadingsButton
        function ResumeReadingsButtonPushed(app, event)
            app.pauseValue = app.pauseValue + 1;
            app.FLDataLamp.Color = [0 1 0];
            app.TextArea.Value = 'Unpausing data.';
        end

        % Button pushed function: ResetDisplacedValuesButton
        function ResetDisplacedValuesButtonPushed(app, event)
            app.FLDialIndicator.Value = 0;
            app.FRDialIndicator.Value = 0;
            app.RLDialIndicator.Value = 0;
            app.RRDialIndicator.Value = 0;
            app.TextArea.Value = 'Displaced position Values were reset.';
        end

        % Button pushed function: GetTorsionalRigidityButton
        function GetTorsionalRigidityButtonPushed(app, event)
            
            %~~~~~~~~~~~THIS NEEDS TO BE ADJUSTED BELOW~~~~~~~~~~~~~
            %~~~~~~~~~~~THIS NEEDS TO BE ADJUSTED BELOW~~~~~~~~~~~~~
            %~~~~~~~~~~~THIS NEEDS TO BE ADJUSTED BELOW~~~~~~~~~~~~~
            %~~~~~~~~~~~THIS NEEDS TO BE ADJUSTED BELOW~~~~~~~~~~~~~
             %old formula below on comments
             %Ct=(Load Cell Force (FL Hub) * (½ * distance between "Jack Screws ))/
             %(arctan[(displacement dial indicator (FL Hub) + displacement dial indicator (FR Hub))/Distance between dial indicators]
             %app.TextArea.Value = 'Calculating torsional rigidity...';
             %app.jackScrewDistanceFront = app.FrontHubDistance.Value;
             %app.jackScrewDistanceRear = app.RearHubDistance;
             %app.dialIndDistance = app.FrontDialIndicatorDistance.Value;
                %averaging torque read at each load cell for accuracy
             %app.torque = mean(abs(app.FLLoadCell.Value * 0.5 * app.jackScrewDistanceFront)+abs(app.FRLoadCell.Value * 0.5 * app.jackScrewDistanceFront))*0.0098066500286389*0.22480894387/12;
                %converting in-g/deg to in-N/deg to ft-lbf/deg
             %app.torsionalRigidity = (app.torque / app.deformationAngleFront);
             
             %new formula
             app.TextArea.Value = 'Calculating torsional rigidity...'; %only using torque from front
             app.torque = mean(abs(app.FLLoadCell.Value)+abs(app.FRLoadCell.Value))*(app.FrontHubDistance.Value/2);
             %need to convert from in-g/deg to ft-lbf/deg
             app.torque = app.torque*0.0098066500286389*0.22480894387/12;
             app.torsionalRigidity = app.torque/(abs(app.DeformationAngle1EditField.Value)-abs(app.DeformationAngle1EditField_2.Value));
             
                %setting up the excel save
            app.torsionalRigidityTable = [app.torsionalRigidityTable; toc() (abs(app.DeformationAngle1EditField.Value)-abs(app.DeformationAngle1EditField_2.Value)) app.torque app.torsionalRigidity];
            app.UITable.Data = [app.torsionalRigidityTable];
            app.UITable.ColumnFormat = {'bank' 'bank' 'bank' 'bank'};
            app.TorsionalRigidityOutput.Value = app.torsionalRigidity;
            app.TextArea.Value = 'Torsional rigidity has been calculated!';
        end

        % Value changed function: SaveTorsionalRigidity
        function SaveTorsionalRigidityValueChanged(app, event)
            app.TextArea.Value = 'Now saving...';
            Value = app.SaveTorsionalRigidity.Value;
            if Value == 'Yes' %#ok<BDSCA> 
                app.FLDataLamp.Color = [0 0 1];
                % checking for old files
                if isfile('Torsional_Rigidity_Snapshot.xlsx')
                    delete 'Torsional_Rigidity_Snapshot.xlsx';
                    delete 'Load_Time_Values.xlsx';
                end
                writecell({'Time','Deformation Angle','Torque','Torsional Rigidity'},'Torsional_Rigidity_Snapshot.xlsx');
                writematrix(app.UITable.Data,'Torsional_Rigidity_Snapshot.xlsx',"Range","A2");
                writecell({'Time [s]','Load Cell 1 [g]', 'Load Cell 2 [g]', 'Load Cell 3 [g]', 'Load Cell 4 [g]'},'Load_Time_Values.xlsx');
                writematrix([app.xVals', app.yVals1', app.yVals2', app.yVals3', app.yVals4'],'Load_Time_Values.xlsx',"Range","A2");
                app.SaveTorsionalRigidity.Value = 'No';
            end
            app.FLDataLamp.Color = [0 1 0];
            app.TextArea.Value = 'The files have been saved!';
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            Value = app.Button_3.Value;
            if Value == 1
                app.Button_2.Value = 0;
                app.Button.Value = 0;
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            Value = app.Button_2.Value;
            if Value == 1
                app.Button_3.Value = 0;
                app.Button.Value = 0;
            end
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            Value = app.Button.Value;
            if Value == 1
                app.Button_2.Value = 0;
                app.Button_3.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ArduinoConnectionPanel = uipanel(app.LeftPanel);
            app.ArduinoConnectionPanel.Title = 'Arduino Connection';
            app.ConnectButton = uibutton(app.ArduinoConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ArduinoDropDownLabel = uilabel(app.ArduinoConnectionPanel);
            app.ArduinoDropDownLabel.Text = 'Arduino:';
            app.ArduinoDropDown = uidropdown(app.ArduinoConnectionPanel);
            app.ArduinoDropDown.Items = {'Uno', 'Mega'};
            app.ArduinoDropDown.Value = 'Uno';
            app.SerialPortEditFieldLabel = uilabel(app.ArduinoConnectionPanel);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.ArduinoConnectionPanel, 'text');
            app.SerialPortEditField.Value = 'COM3';
            app.ConnectionLamp = uilamp(app.ArduinoConnectionPanel);
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Hello!'};
            app.LoadCellGroup = uitabgroup(app.LeftPanel);
            app.FrontLeftTab = uitab(app.LoadCellGroup);
            app.FrontLeftTab.Title = 'Front Left';
            app.DataOutPinEditFieldLabel = uilabel(app.FrontLeftTab);
            app.DataOutPinEditFieldLabel.Text = 'Data Out Pin';
            app.FLDataOutPinEditField = uieditfield(app.FrontLeftTab, 'text');
            app.FLDataOutPinEditField.Value = 'D2';
            app.ClockSCKPinEditFieldLabel = uilabel(app.FrontLeftTab);
            app.ClockSCKPinEditFieldLabel.Text = 'Clock (SCK) Pin';
            app.FLClockSCKPinEditField = uieditfield(app.FrontLeftTab, 'text');
            app.FLClockSCKPinEditField.Value = 'D3';
            app.TareValueEditFieldLabel = uilabel(app.FrontLeftTab);
            app.TareValueEditFieldLabel.Text = 'Tare Value';
            app.FLTareValueEditField = uieditfield(app.FrontLeftTab, 'numeric');
            app.FLTareValueEditField.ValueDisplayFormat = '%.0f';
            app.FLTareValueEditField.Value = 8431740.8;
            app.ScaleFactorEditFieldLabel = uilabel(app.FrontLeftTab);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.FLScaleFactorEditField = uieditfield(app.FrontLeftTab, 'numeric');
            app.FLScaleFactorEditField.ValueDisplayFormat = '%.2f';
            app.FLScaleFactorEditField.Value = 46.3390447803311;
            app.MaxLoadgEditFieldLabel = uilabel(app.FrontLeftTab);
            app.MaxLoadgEditFieldLabel.Text = 'Max Load (g)';
            app.FLMaxLoadgEditField = uieditfield(app.FrontLeftTab, 'numeric');
            app.FLMaxLoadgEditField.ValueDisplayFormat = '%.0f';
            app.FLMaxLoadgEditField.Value = 100000;
            app.FLTareButton = uibutton(app.FrontLeftTab, 'push');
            app.FLTareButton.ButtonPushedFcn = createCallbackFcn(app, @FLTareButtonPushed, true);
            app.FLTareButton.Text = {'Tare'; ''};
            app.FLCalibrateButton = uibutton(app.FrontLeftTab, 'push');
            app.FLCalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @FLCalibrateButtonPushed, true);
            app.FLCalibrateButton.Text = {'Calibrate'; ''};
            app.KnownWeightgLabel = uilabel(app.FrontLeftTab);
            app.KnownWeightgLabel.Text = 'Known Weight (g)';
            app.FLKnownWeightgEditField = uieditfield(app.FrontLeftTab, 'numeric');
            app.FLKnownWeightgEditField.ValueDisplayFormat = '%.2f';
            app.FLKnownWeightgEditField.Value = 284;
            app.FrontRightTab = uitab(app.LoadCellGroup);
            app.FrontRightTab.Title = 'Front Right';
            app.DataOutPinEditFieldLabel_2 = uilabel(app.FrontRightTab);
            app.DataOutPinEditFieldLabel_2.Text = 'Data Out Pin';
            app.FRDataOutPinEditField = uieditfield(app.FrontRightTab, 'text');
            app.FRDataOutPinEditField.Value = 'D4';
            app.ClockSCKPinEditFieldLabel_2 = uilabel(app.FrontRightTab);
            app.ClockSCKPinEditFieldLabel_2.Text = 'Clock (SCK) Pin';
            app.FRClockSCKPinEditField = uieditfield(app.FrontRightTab, 'text');
            app.FRClockSCKPinEditField.Value = 'D5';
            app.TareValueEditFieldLabel_2 = uilabel(app.FrontRightTab);
            app.TareValueEditFieldLabel_2.Text = 'Tare Value';
            app.FRTareValueEditField = uieditfield(app.FrontRightTab, 'numeric');
            app.FRTareValueEditField.ValueDisplayFormat = '%.0f';
            app.FRTareValueEditField.Value = 8385229.7;
            app.ScaleFactorEditFieldLabel_2 = uilabel(app.FrontRightTab);
            app.ScaleFactorEditFieldLabel_2.Text = 'Scale Factor';
            app.FRScaleFactorEditField = uieditfield(app.FrontRightTab, 'numeric');
            app.FRScaleFactorEditField.ValueDisplayFormat = '%.2f';
            app.FRScaleFactorEditField.Value = 48.7322086809292;
            app.MaxLoadgEditFieldLabel_2 = uilabel(app.FrontRightTab);
            app.MaxLoadgEditFieldLabel_2.Text = 'Max Load (g)';
            app.FRMaxLoadgEditField = uieditfield(app.FrontRightTab, 'numeric');
            app.FRMaxLoadgEditField.ValueDisplayFormat = '%.0f';
            app.FRMaxLoadgEditField.Value = 100000;
            app.FRTareButton = uibutton(app.FrontRightTab, 'push');
            app.FRTareButton.ButtonPushedFcn = createCallbackFcn(app, @FRTareButtonPushed, true);
            app.FRTareButton.Text = {'Tare'; ''};
            app.FRCalibrateButton = uibutton(app.FrontRightTab, 'push');
            app.FRCalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @FRCalibrateButtonPushed, true);
            app.FRCalibrateButton.Text = {'Calibrate'; ''};
            app.KnownWeightgLabel_2 = uilabel(app.FrontRightTab);
            app.KnownWeightgLabel_2.Text = 'Known Weight (g)';
            app.FRKnownWeightgEditField = uieditfield(app.FrontRightTab, 'numeric');
            app.FRKnownWeightgEditField.ValueDisplayFormat = '%.2f';
            app.FRKnownWeightgEditField.Value = 2267.96;
            app.RearLeftTab = uitab(app.LoadCellGroup);
            app.RearLeftTab.Title = 'Rear Left';
            app.DataOutPinEditFieldLabel_3 = uilabel(app.RearLeftTab);
            app.DataOutPinEditFieldLabel_3.Text = 'Data Out Pin';
            app.RLDataOutPinEditField = uieditfield(app.RearLeftTab, 'text');
            app.RLDataOutPinEditField.Value = 'D7';
            app.ClockSCKPinEditFieldLabel_3 = uilabel(app.RearLeftTab);
            app.ClockSCKPinEditFieldLabel_3.Text = 'Clock (SCK) Pin';
            app.RLClockSCKPinEditField = uieditfield(app.RearLeftTab, 'text');
            app.RLClockSCKPinEditField.Value = 'D6';
            app.TareValueEditFieldLabel_3 = uilabel(app.RearLeftTab);
            app.TareValueEditFieldLabel_3.Text = 'Tare Value';
            app.RLTareValueEditField = uieditfield(app.RearLeftTab, 'numeric');
            app.RLTareValueEditField.ValueDisplayFormat = '%.0f';
            app.RLTareValueEditField.Value = 8332506.7;
            app.ScaleFactorEditFieldLabel_3 = uilabel(app.RearLeftTab);
            app.ScaleFactorEditFieldLabel_3.Text = 'Scale Factor';
            app.RLScaleFactorEditField = uieditfield(app.RearLeftTab, 'numeric');
            app.RLScaleFactorEditField.ValueDisplayFormat = '%.2f';
            app.RLScaleFactorEditField.Value = 45.3827826086954;
            app.MaxLoadgEditFieldLabel_3 = uilabel(app.RearLeftTab);
            app.MaxLoadgEditFieldLabel_3.Text = 'Max Load (g)';
            app.RLMaxLoadgEditField = uieditfield(app.RearLeftTab, 'numeric');
            app.RLMaxLoadgEditField.ValueDisplayFormat = '%.0f';
            app.RLMaxLoadgEditField.Value = 100000;
            app.RLTareButton = uibutton(app.RearLeftTab, 'push');
            app.RLTareButton.ButtonPushedFcn = createCallbackFcn(app, @RLTareButtonPushed, true);
            app.RLTareButton.Text = {'Tare'; ''};
            app.RLCalibrateButton = uibutton(app.RearLeftTab, 'push');
            app.RLCalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @RLCalibrateButtonPushed, true);
            app.RLCalibrateButton.Text = {'Calibrate'; ''};
            app.KnownWeightgLabel_3 = uilabel(app.RearLeftTab);
            app.KnownWeightgLabel_3.Text = 'Known Weight (g)';
            app.RLKnownWeightgEditField = uieditfield(app.RearLeftTab, 'numeric');
            app.RLKnownWeightgEditField.ValueDisplayFormat = '%.2f';
            app.RLKnownWeightgEditField.Value = 284;
            app.RearRightTab = uitab(app.LoadCellGroup);
            app.RearRightTab.Title = 'Rear Right';
            app.DataOutPinEditFieldLabel_4 = uilabel(app.RearRightTab);
            app.DataOutPinEditFieldLabel_4.Text = 'Data Out Pin';
            app.RRDataOutPinEditField = uieditfield(app.RearRightTab, 'text');
            app.RRDataOutPinEditField.Value = 'D8';
            app.ClockSCKPinEditFieldLabel_4 = uilabel(app.RearRightTab);
            app.ClockSCKPinEditFieldLabel_4.Text = 'Clock (SCK) Pin';
            app.RRClockSCKPinEditField = uieditfield(app.RearRightTab, 'text');
            app.RRClockSCKPinEditField.Value = 'D9';
            app.TareValueEditFieldLabel_4 = uilabel(app.RearRightTab);
            app.TareValueEditFieldLabel_4.Text = 'Tare Value';
            app.RRTareValueEditField = uieditfield(app.RearRightTab, 'numeric');
            app.RRTareValueEditField.ValueDisplayFormat = '%.0f';
            app.RRTareValueEditField.Value = 8332506.7;
            app.ScaleFactorEditFieldLabel_4 = uilabel(app.RearRightTab);
            app.ScaleFactorEditFieldLabel_4.Text = 'Scale Factor';
            app.RRScaleFactorEditField = uieditfield(app.RearRightTab, 'numeric');
            app.RRScaleFactorEditField.ValueDisplayFormat = '%.2f';
            app.RRScaleFactorEditField.Value = 45.3827826086954;
            app.MaxLoadgEditFieldLabel_4 = uilabel(app.RearRightTab);
            app.MaxLoadgEditFieldLabel_4.Text = 'Max Load (g)';
            app.RRMaxLoadgEditField = uieditfield(app.RearRightTab, 'numeric');
            app.RRMaxLoadgEditField.ValueDisplayFormat = '%.0f';
            app.RRMaxLoadgEditField.Value = 100000;
            app.RRTareButton = uibutton(app.RearRightTab, 'push');
            app.RRTareButton.ButtonPushedFcn = createCallbackFcn(app, @RRTareButtonPushed, true);
            app.RRTareButton.Text = {'Tare'; ''};
            app.RRCalibrateButton = uibutton(app.RearRightTab, 'push');
            app.RRCalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @RRCalibrateButtonPushed, true);
            app.RRCalibrateButton.Text = {'Calibrate'; ''};
            app.KnownWeightgLabel_4 = uilabel(app.RearRightTab);
            app.KnownWeightgLabel_4.Text = 'Known Weight (g)';
            app.RRKnownWeightgEditField = uieditfield(app.RearRightTab, 'numeric');
            app.RRKnownWeightgEditField.ValueDisplayFormat = '%.2f';
            app.RRKnownWeightgEditField.Value = 284;
            app.Panel = uipanel(app.LeftPanel);
            app.FLReceiveDataButton = uibutton(app.Panel, 'push');
            app.FLReceiveDataButton.ButtonPushedFcn = createCallbackFcn(app, @FLReceiveDataButtonPushed, true);
            app.FLReceiveDataButton.Text = 'Receive Data';
            app.FLDataLamp = uilamp(app.Panel);
            app.FLStopDataButton = uibutton(app.Panel, 'push');
            app.FLStopDataButton.ButtonPushedFcn = createCallbackFcn(app, @FLStopDataButtonPushed, true);
            app.FLStopDataButton.Text = {'Stop Data'; ''};
            app.PauseReadingsButton = uibutton(app.Panel, 'push');
            app.PauseReadingsButton.ButtonPushedFcn = createCallbackFcn(app, @PauseReadingsButtonPushed, true);
            app.PauseReadingsButton.Text = 'Pause Readings';
            app.ResumeReadingsButton = uibutton(app.Panel, 'push');
            app.ResumeReadingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeReadingsButtonPushed, true);
            app.ResumeReadingsButton.Text = 'Resume Readings';
            app.CenterPanel = uipanel(app.GridLayout);
            app.FLLoadCell = uieditfield(app.CenterPanel, 'numeric');
            app.FLLoadCell.ValueDisplayFormat = '%.2f';
            app.RetrieveRawValueButton = uibutton(app.CenterPanel, 'push');
            app.RetrieveRawValueButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveRawValueButtonPushed, true);
            app.RetrieveRawValueButton.Text = {'Retrieve Raw Value'; ''; ''};
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, {'Live Data Acquisition'; ''})
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Load (g)')
            app.FLLabel = uilabel(app.CenterPanel);
            app.FLLabel.Text = 'FL';
            app.LoadCellLabel = uilabel(app.CenterPanel);
            app.LoadCellLabel.Text = 'Load Cell:';
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = {'Time [s]'; 'F-R Deformation Angle [°]'; 'Torque [ft-lbf]'; 'Torsional Rigidity [ft-lbf/°]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.FRLoadCell = uieditfield(app.CenterPanel, 'numeric');
            app.FRLoadCell.ValueDisplayFormat = '%.2f';
            app.FRLabel = uilabel(app.CenterPanel);
            app.FRLabel.Text = 'FR';
            app.RLLoadCell = uieditfield(app.CenterPanel, 'numeric');
            app.RLLoadCell.ValueDisplayFormat = '%.2f';
            app.RLLabel = uilabel(app.CenterPanel);
            app.RLLabel.Text = 'RL';
            app.RRLoadCell = uieditfield(app.CenterPanel, 'numeric');
            app.RRLoadCell.ValueDisplayFormat = '%.2f';
            app.RRLabel = uilabel(app.CenterPanel);
            app.RRLabel.Text = 'RR';
            app.RightPanel = uipanel(app.GridLayout);
            app.TorsionalRigidityCalculationPanel = uipanel(app.RightPanel);
            app.TorsionalRigidityCalculationPanel.Title = 'Torsional Rigidity Calculation';
            app.GetTorsionalRigidityButton = uibutton(app.TorsionalRigidityCalculationPanel, 'push');
            app.GetTorsionalRigidityButton.ButtonPushedFcn = createCallbackFcn(app, @GetTorsionalRigidityButtonPushed, true);
            app.GetTorsionalRigidityButton.Text = 'Get Torsional Rigidity';
            app.TorsionalRigidityOutput = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.TorsionalRigidityOutput.ValueDisplayFormat = '%.2f';
            app.DeformationAngleLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.DeformationAngleLabel.Text = {'Deformation Angle (°)'; ''};
            app.DeformationAngle1EditField = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.DeformationAngle1EditField.ValueDisplayFormat = '%.2f';
            app.Button = uibutton(app.TorsionalRigidityCalculationPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '8';
            app.Button_2 = uibutton(app.TorsionalRigidityCalculationPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '6';
            app.Button_3 = uibutton(app.TorsionalRigidityCalculationPanel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '4';
            app.Button_3.Value = true;
            app.DialIndicatorsLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.DialIndicatorsLabel.Text = '# Dial Indicators:';
            app.DeformationAngle1EditField_2 = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.DeformationAngle1EditField_2.ValueDisplayFormat = '%.2f';
            app.FLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.FLabel.Text = 'F:';
            app.RLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.RLabel.Text = 'R:';
            app.SaveLamp = uilamp(app.RightPanel);
            app.SaveAllValuestoExcelSheetsLabel = uilabel(app.RightPanel);
            app.SaveAllValuestoExcelSheetsLabel.Text = 'Save All Values to Excel Sheets?';
            app.SaveTorsionalRigidity = uiswitch(app.RightPanel, 'slider');
            app.SaveTorsionalRigidity.Items = {'No', 'Yes'};
            app.SaveTorsionalRigidity.ValueChangedFcn = createCallbackFcn(app, @SaveTorsionalRigidityValueChanged, true);
            app.SaveTorsionalRigidity.Value = 'No';
            app.LoadCellValuesandTorsionalRigidityValueswithTimeLabel = uilabel(app.RightPanel);
            app.LoadCellValuesandTorsionalRigidityValueswithTimeLabel.Text = '(Load Cell Values and Torsional Rigidity Values with Time)';
            app.DialIndicatorSettingsPanel = uipanel(app.RightPanel);
            app.DialIndicatorSettingsPanel.Title = 'Dial Indicator Settings';
            app.TabGroup = uitabgroup(app.DialIndicatorSettingsPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '4';
            app.DialIndicatorDisplacementsInLabel = uilabel(app.Tab);
            app.DialIndicatorDisplacementsInLabel.Text = 'Dial Indicator Displacements[In.]';
            app.FrontLeftLabel = uilabel(app.Tab);
            app.FrontLeftLabel.Text = 'Front Left:';
            app.FLDialIndicator = uieditfield(app.Tab, 'numeric');
            app.FrontRightLabel = uilabel(app.Tab);
            app.FrontRightLabel.Text = 'Front Right:';
            app.FRDialIndicator = uieditfield(app.Tab, 'numeric');
            app.FRDialIndicator.Value = 0.1;
            app.RearLeftLabel = uilabel(app.Tab);
            app.RearLeftLabel.Text = 'Rear Left:';
            app.RLDialIndicator = uieditfield(app.Tab, 'numeric');
            app.RearRightLabel = uilabel(app.Tab);
            app.RearRightLabel.Text = 'Rear Right:';
            app.RRDialIndicator = uieditfield(app.Tab, 'numeric');
            app.RRDialIndicator.Value = 0.3;
            app.ResetDisplacedValuesButton = uibutton(app.Tab, 'push');
            app.ResetDisplacedValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDisplacedValuesButtonPushed, true);
            app.ResetDisplacedValuesButton.Text = 'Reset Displaced Values';
            app.DistanceBetweenDialIndicatorsinEditFieldLabel = uilabel(app.Tab);
            app.DistanceBetweenDialIndicatorsinEditFieldLabel.Text = 'Distance Between Dial Indicators [in.]';
            app.FrontDialIndicatorDistance = uieditfield(app.Tab, 'numeric');
            app.FrontDialIndicatorDistance.LowerLimitInclusive = 'off';
            app.FrontDialIndicatorDistance.Value = 11.675;
            app.FrontLabel_2 = uilabel(app.Tab);
            app.FrontLabel_2.Text = 'Front:';
            app.RearLabel = uilabel(app.Tab);
            app.RearLabel.Text = 'Rear:';
            app.RearDialIndicatorDistance = uieditfield(app.Tab, 'numeric');
            app.RearDialIndicatorDistance.Value = 13;
            app.DistanceBetweenHubsinLabel = uilabel(app.Tab);
            app.DistanceBetweenHubsinLabel.Text = 'Distance Between Hubs [in.]';
            app.FrontHubDistance = uieditfield(app.Tab, 'numeric');
            app.FrontHubDistance.Value = 53.375;
            app.FrontLabel_3 = uilabel(app.Tab);
            app.FrontLabel_3.Text = 'Front:';
            app.RearLabel_2 = uilabel(app.Tab);
            app.RearLabel_2.Text = 'Rear:';
            app.RearHubDistance = uieditfield(app.Tab, 'numeric');
            app.RearHubDistance.Value = 54.125;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '6';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uhfsae_data_acquisition_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end